classdef invFilterDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        HasznltszrkListBoxLabel   matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        MdostandszrDropDownLabel  matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        GaindBLabel               matlab.ui.control.Label
        gainField                 matlab.ui.control.NumericEditField
        fcHzLabel                 matlab.ui.control.Label
        fcField                   matlab.ui.control.NumericEditField
        bwoctaveLabel             matlab.ui.control.Label
        bwField                   matlab.ui.control.NumericEditField
        SliderLabel               matlab.ui.control.Label
        gainSlider                matlab.ui.control.Slider
        Slider_2Label             matlab.ui.control.Label
        fcSlider                  matlab.ui.control.Slider
        Slider_3Label             matlab.ui.control.Label
        bwSlider                  matlab.ui.control.Slider
        resetButton               matlab.ui.control.Button
        finishButton              matlab.ui.control.Button
    end


    properties (Access = public)
        OriginalFilters % Filters from the algorithm
        SelectedFilter
        ModFilters
        Params
        FinalFilters
        OriginalMic
        GainValues
        FcValues
        BwValues
        GainLimit
        FcLimit
        BwLimit
    end

    methods (Access = private)
    
        function plotModFilter(app,changingValue,paramNum)
            fs = 44100;
            f_interp_plot = logspace(log10(app.Params.START_FREQ),log10(app.Params.END_FREQ),app.Params.PLOT_FLINES);
            i = app.SelectedFilter;
            if (app.ModFilters(i,6) == 0) % parametric filter
                switch(paramNum)
                    case 1
                        [He,~] = parametricEQ(changingValue,app.ModFilters(i,2),app.ModFilters(i,3),fs,f_interp_plot);
                    case 2
                        [He,~] = parametricEQ(app.ModFilters(i,1),changingValue,app.ModFilters(i,3),fs,f_interp_plot);
                    case 3
                        [He,~] = parametricEQ(app.ModFilters(i,1),app.ModFilters(i,2),changingValue,fs,f_interp_plot);
                end
            else % butterworth filter
                if (paramNum == 2)
                    if(app.ModFilters(i,6) == 1)
                        [b,a] = butter(1,changingValue/(fs/2),'high');
                    else
                        [b,a] = butter(1,changingValue/(fs/2),'low');
                    end
                end
                [He,~] = freqz(b,a,f_interp_plot,fs);
                He = 20*log10(abs(He));
            end
            semilogx(app.UIAxes3,f_interp_plot,He,'DisplayName', string(i))
            hold(app.UIAxes3,'on')
            plotModTransfer(app)
            hold(app.UIAxes3,'off')
        end
        
        function plotModTransfer(app)
            fs = 44100;
            f_interp_plot = logspace(log10(app.Params.START_FREQ),log10(app.Params.END_FREQ),app.Params.PLOT_FLINES);
            f_interp = logspace(log10(app.Params.START_FREQ),log10(app.Params.END_FREQ),app.Params.COMP_FLINES);
            H_mic = interp1(f_interp_plot,app.OriginalMic,f_interp,'pchip');
            He = H_mic;
            for i = 1:size(app.ModFilters,1)
                if (app.ModFilters(i,7) == 1)
                    if (app.ModFilters(i,6) == 0)
                        [H,~] = parametricEQ(app.ModFilters(i,1),app.ModFilters(i,2),app.ModFilters(i,3),fs,f_interp_plot);
                    else % butterworth filter
                        if(app.ModFilters(i,6) == 1)
                            [b,a] = butter(1,app.ModFilters(i,2)/(fs/2),'high');
                        else
                            [b,a] = butter(1,app.ModFilters(i,2)/(fs/2),'low');
                        end
                        [H,~] = freqz(b,a,f_interp_plot,fs);
                        H = 20*log10(abs(H));
                    end
                    He = He + H;
                end
            end
            semilogx(app.UIAxes3,f_interp_plot,He)
        end
        
        function enableModFields(app)
            app.gainField.Enable = 'on';
            app.fcField.Enable = 'on';
            app.bwField.Enable = 'on';
            app.gainSlider.Enable = 'on';
            app.fcSlider.Enable = 'on';
            app.bwSlider.Enable = 'on';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [filters,H_mic] = invFilterDesign(app.UIAxes,app.UIAxes2);
            %load('filters.mat', 'filters','H_mic');
            app.OriginalMic = H_mic;
            app.OriginalFilters = filters;
            app.ModFilters = [app.OriginalFilters ones(size(app.OriginalFilters,1),1)];
            items = cell(1,size(app.OriginalFilters,1));
            for i=1:size(app.OriginalFilters,1)
                items{i} = ['Szűrő ' num2str(i,'%d')];  
            end
            app.ListBox.Items = items;
            app.DropDown.Items = items;
            app.Params = load('config.mat');
            app.ListBox.Value = items;
            app.GainLimit = [-30 30];
            app.FcLimit = [1 22050];
            app.BwLimit = [0 20];
            app.GainValues = linspace(app.GainLimit(1),app.GainLimit(2),100);
            app.FcValues = logspace(log10(app.FcLimit(1)),log10(app.FcLimit(2)),100);
            app.BwValues = linspace(app.BwLimit(1),app.BwLimit(2),100);
            app.gainField.Limits = app.GainLimit;
            app.fcField.Limits = [app.FcLimit(1) app.FcLimit(2)+1];
            app.bwField.Limits = app.BwLimit;
            
%             a = round([app.GainValues(1:20:end) app.GainValues(end)]);
%             app.gainSlider.MajorTickLabels = cellstr(num2str(a));
%             app.fcSlider.MajorTickLabels = cellstr(round(app.FcValues(1:20:end)));
%             app.bwSlider.MajorTickLabels = cellstr(round(app.BwValues(1:20:end)));
% %             minorTicks = [logspace(log10(500),log10(1),10)*-1 logspace(log10(1),log10(25000),90)];
%             majorTicks = minorTicks(1:5:end);
%             minorTicks(1:5:end) = [];
%             app.fcKnob.MajorTicks = majorTicks;
%             app.fcKnob.MinorTicks = minorTicks;

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
             value = app.DropDown.Value;
            for i = 1:size(app.ModFilters,1)
                if (isequal(value,app.ListBox.Items{i}))
                    app.SelectedFilter = i;                                    
                    xlim(app.UIAxes3,[app.Params.START_FREQ app.Params.END_FREQ])
                    plotModFilter(app,app.ModFilters(i,2),2);
                    
                    app.gainField.Value = app.ModFilters(i,1);
                    app.fcField.Value = app.ModFilters(i,2);
                    app.bwField.Value = app.ModFilters(i,3);
                    %TODO slider init
                    if(app.ModFilters(i,6) ~= 0) % ha lpf vagy hpf a gain és bw nem állítható
                        app.gainField.Enable = 'off';
                        app.bwField.Enable = 'off';
                        %TODO slider disable
                    end
                    %TODO enable
                    break;
                end
            end
            enableModFields(app);
            
        end

        % Value changed function: gainField
        function gainFieldValueChanged(app, event)
            changingValue = event.Value;
            app.ModFilters(app.SelectedFilter,1) = changingValue;
            plotModFilter(app,changingValue,1);
            app.gainSlider.Value = find((app.GainValues(1:end-1) <= changingValue) & (app.GainValues(2:end) > changingValue),1);
        end

        % Value changed function: fcField
        function fcFieldValueChanged(app, event)
            changingValue = event.Value;
            app.ModFilters(app.SelectedFilter,2) = changingValue;
            plotModFilter(app,changingValue,2);
            app.fcSlider.Value = find((app.FcValues(1:end-1) <= changingValue) & (app.FcValues(2:end) > changingValue),1);
        end

        % Value changed function: bwField
        function bwFieldValueChanged(app, event)
            changingValue = event.Value;
            app.ModFilters(app.SelectedFilter,3) = changingValue;
            plotModFilter(app,changingValue,3);
            app.bwSlider.Value = find((app.BwValues(1:end-1) <= changingValue)&(app.BwValues(2:end) > changingValue),1);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.DropDown.Items = value;
            for i = 1:size(app.ListBox.Items,2)
                for j = 1:size(value,2)
                    if (isequal(value(j), app.ListBox.Items(i)))
                        app.ModFilters(i,7) = 1;
                        break;
                    else
                        app.ModFilters(i,7) = 0;
                    end
                end
            end
            DropDownValueChanged(app, event); 
        end

        % Value changing function: gainSlider
        function gainSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if(changingValue == 0)
                changingValue = 1;
            end
          
            ev.Value = app.GainValues(changingValue);
            %gainFieldValueChanged(app, ev);
            app.ModFilters(app.SelectedFilter,1) = app.GainValues(changingValue);
            plotModFilter(app,app.GainValues(changingValue),1);
            app.gainField.Value = ev.Value;
        end

        % Value changing function: fcSlider
        function fcSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if(changingValue == 0)
                changingValue = 1;
            end
          
            ev.Value = app.FcValues(changingValue);
            %fcFieldValueChanged(app, ev);
            app.ModFilters(app.SelectedFilter,2) = app.FcValues(changingValue);
            plotModFilter(app, app.FcValues(changingValue),2);
            app.fcField.Value = ev.Value;
            
        end

        % Value changing function: bwSlider
        function bwSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if(changingValue == 0)
                changingValue = 1;
            end
          
            ev.Value = app.BwValues(changingValue);
            %bwFieldValueChanged(app, ev);
            app.ModFilters(app.SelectedFilter,3) = app.BwValues(changingValue);
            plotModFilter(app,app.BwValues(changingValue),3);
            app.bwField.Value = ev.Value;
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.ModFilters = [app.OriginalFilters ones(size(app.OriginalFilters,1),1)];
            items = cell(1,size(app.OriginalFilters,1));
            for i=1:size(app.OriginalFilters,1)
                items{i} = ['Szűrő ' num2str(i,'%d')];  
            end
            app.ListBox.Items = items;
            app.ListBox.Value = items;
            app.DropDown.Items = items;
            
            
        end

        % Button pushed function: finishButton
        function finishButtonPushed(app, event)
            %TODO json parse and concat
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Átvitel változása')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Szűrők')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Végleges átvitel')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.HasznltszrkListBoxLabel = uilabel(app.UIFigure);
            app.HasznltszrkListBoxLabel.Text = 'Használt szűrők';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.MdostandszrDropDownLabel = uilabel(app.UIFigure);
            app.MdostandszrDropDownLabel.Text = 'Módosítandó szűrő';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.GaindBLabel = uilabel(app.UIFigure);
            app.GaindBLabel.Text = 'Gain [dB]';
            app.gainField = uieditfield(app.UIFigure, 'numeric');
            app.gainField.ValueChangedFcn = createCallbackFcn(app, @gainFieldValueChanged, true);
            app.gainField.Enable = 'off';
            app.fcHzLabel = uilabel(app.UIFigure);
            app.fcHzLabel.Text = 'fc [Hz]';
            app.fcField = uieditfield(app.UIFigure, 'numeric');
            app.fcField.ValueChangedFcn = createCallbackFcn(app, @fcFieldValueChanged, true);
            app.fcField.Enable = 'off';
            app.bwoctaveLabel = uilabel(app.UIFigure);
            app.bwoctaveLabel.Text = 'bw [octave]';
            app.bwField = uieditfield(app.UIFigure, 'numeric');
            app.bwField.ValueChangedFcn = createCallbackFcn(app, @bwFieldValueChanged, true);
            app.bwField.Enable = 'off';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.gainSlider = uislider(app.UIFigure);
            app.gainSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.gainSlider.ValueChangingFcn = createCallbackFcn(app, @gainSliderValueChanging, true);
            app.gainSlider.Enable = 'off';
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.fcSlider = uislider(app.UIFigure);
            app.fcSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.fcSlider.ValueChangingFcn = createCallbackFcn(app, @fcSliderValueChanging, true);
            app.fcSlider.Enable = 'off';
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = 'Slider';
            app.bwSlider = uislider(app.UIFigure);
            app.bwSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.bwSlider.ValueChangingFcn = createCallbackFcn(app, @bwSliderValueChanging, true);
            app.bwSlider.Enable = 'off';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'Visszaállítás';
            app.finishButton = uibutton(app.UIFigure, 'push');
            app.finishButton.ButtonPushedFcn = createCallbackFcn(app, @finishButtonPushed, true);
            app.finishButton.Text = 'Kész';
        end
    end

    methods (Access = public)

        % Construct app
        function app = invFilterDesignApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end